classdef Ollie_doc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OllieUIFigure           matlab.ui.Figure
        OllieforDoctorsLabel    matlab.ui.control.Label
        SelectButton            matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
        FilePathEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        ChangeDiagnosisButton   matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        Label                   matlab.ui.control.Label
    end


    properties (Access = private)
        filepath 
        filename
        savepath
        score
        isSelected
        net
        index = []
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net = load('net-new.mat');
            imshow(imread('AutoOCT.png'),'Parent',app.UIAxes);
            
            app.UITable.BackgroundColor = [.149,.7608,.5059;0.1639 0.8369 0.5565];
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Get filepath
            app.OllieUIFigure.Visible = 'off';
            [localfilename,localfilepath] = uigetfile('*.png','MultiSelect','on');
            app.OllieUIFigure.Visible = 'on';
            if localfilepath ~= 0 
                app.filename = localfilename;
                app.filepath = localfilepath;
                app.FilePathEditField.Value = app.filepath;
                
                % Diagnose and generate table
                app.score = cell(size(app.filename,2),1);
                app.isSelected = cell(size(app.filename,2),1);
                for i = 1:size(app.filename,2)
                    img = imread([app.filepath app.filename{i}]);
                    if min(size(img)==[496,768,3]) == 0
                        img = imresize(img,[496,768,3]);
                    end
                    app.score{i} = predict(app.net.net,img);
                    if app.score{i} > 0.8
                        app.isSelected{i} = 'Abnormal';
                    else
                        app.isSelected{i} = '';
                    end
                end
                app.UITable.Data = [app.filename',app.score,app.isSelected];
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Show image
            app.index = event.Indices(1);
            imshow(imread([app.filepath app.filename{app.index}]),'Parent',app.UIAxes);
            title(app.filename{app.index},'Parent',app.UIAxes,'Interpreter', 'none');
            
        end

        % Button pushed function: ChangeDiagnosisButton
        function ChangeDiagnosisButtonPushed(app, event)
            if ~isempty(app.index)
                if strcmp(app.isSelected{app.index}, 'Abnormal')
                    app.isSelected{app.index} = '';
                    app.UITable.Data{app.index,3} = '';
                else
                    app.isSelected{app.index} = 'Abnormal';
                    app.UITable.Data{app.index,3} = 'Abnormal';
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.filepath ~= 0
                app.OllieUIFigure.Visible = 'off';
                app.savepath = uigetdir(app.filepath,'Pick a Directory');
                app.OllieUIFigure.Visible = 'on';
                
                if app.savepath~=0
                    mkdir([app.savepath '/Normal']);
                    mkdir([app.savepath '/Abnormal']);
                    for i = 1:size(app.filename,2)
                        img = imread([app.filepath app.filename{i}]);
                        
                        if strcmp(app.isSelected{i}, 'Abnormal')
                            save([app.savepath '/Abnormal/Abnormal_' app.filename{i}],'img');
                        else
                            save([app.savepath '/Normal/Normal_' app.filename{i}],'img');
                        end
                    end
                    app.Label.Text = ['Saved in ' app.savepath];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OllieUIFigure = uifigure('Visible', 'off');
            app.OllieUIFigure.Name = 'Ollie';
            app.OllieforDoctorsLabel = uilabel(app.OllieUIFigure);
            app.OllieforDoctorsLabel.Text = 'Ollie for Doctors';
            app.SelectButton = uibutton(app.OllieUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.UITable = uitable(app.OllieUIFigure);
            app.UITable.ColumnName = {'Filename'; 'Score'; 'Diagnosis'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.OllieUIFigure);
            app.FilePathEditFieldLabel = uilabel(app.OllieUIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.OllieUIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.ChangeDiagnosisButton = uibutton(app.OllieUIFigure, 'push');
            app.ChangeDiagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDiagnosisButtonPushed, true);
            app.ChangeDiagnosisButton.Text = 'Change Diagnosis';
            app.SaveButton = uibutton(app.OllieUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.OllieUIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.OllieUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ollie_doc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OllieUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OllieUIFigure)
        end
    end
end