classdef Ollie_pat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OllieforselftestUIFigure  matlab.ui.Figure
        OllieforPatientsLabel     matlab.ui.control.Label
        SelectButton              matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        filepath 
        filename
        score
        net
        index = []
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net = load('net-new.mat');
            img = imread('AutoOCT_patientLogo.png');
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Get filepath
            app.OllieforselftestUIFigure.Visible = 'off';
            [localfilename,localfilepath] = uigetfile('*.png','MultiSelect','off');
            app.OllieforselftestUIFigure.Visible = 'on';
            if localfilepath ~= 0
                app.filename = localfilename;
                app.filepath = localfilepath;
%                 app.FilePathEditField.Value = app.filepath;
                
                % Diagnose and generate table
                img = imread([app.filepath app.filename]);
                imshow(img,'Parent',app.UIAxes);
%                 title(app.filename{app.index},'Parent',app.UIAxes,'Interpreter', 'none');
                
                if min(size(img)==[496,768,3]) == 0
                    img = imresize(img,[496,768,3]);
                end
                app.score = predict(app.net.net,img);
                if app.score > 0.8
                    app.Label.Text = 'Time to see a doctor.';
                else
                    app.Label.Text = 'Your eyes are in good health.';
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OllieforselftestUIFigure = uifigure('Visible', 'off');
            app.OllieforselftestUIFigure.Name = 'Ollie for self test';
            app.OllieforPatientsLabel = uilabel(app.OllieforselftestUIFigure);
            app.OllieforPatientsLabel.Text = 'Ollie for Patients';
            app.SelectButton = uibutton(app.OllieforselftestUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Label = uilabel(app.OllieforselftestUIFigure);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.OllieforselftestUIFigure);
            % Show the figure after all components are created
            app.OllieforselftestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ollie_pat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OllieforselftestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OllieforselftestUIFigure)
        end
    end
end